classdef MultVidDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        ContinueButton  matlab.ui.control.Button
        CancelButton    matlab.ui.control.Button
        VidCountLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainAppData 
        processMultContinue
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, nVideos)
            movegui(app.UIFigure,"center");
            app.mainAppData = mainApp;
            % app.UIFigure.position = loc;
            newString = "Selected "+string(nVideos)+" videos for processing. Continue?";
            set(app.VidCountLabel,'Text',newString);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainAppData.processMultContinue = false;
            delete(app)
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.mainAppData.processMultContinue = true;
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainAppData.processMultContinue = false;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.VidCountLabel = uilabel(app.GridLayout);
            app.VidCountLabel.Text = 'Selected N videos for processing. Continue?';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ContinueButton = uibutton(app.GridLayout, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultVidDialog(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end