classdef QuickVid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuickVidAppUIFigure      matlab.ui.Figure
        RotationButtonGroup      matlab.ui.container.ButtonGroup
        RotButton270             matlab.ui.control.ToggleButton
        RotButton180             matlab.ui.control.ToggleButton
        RotButton90              matlab.ui.control.ToggleButton
        RotButtonNone            matlab.ui.control.ToggleButton
        ROIButtonGroup           matlab.ui.container.ButtonGroup
        HorizontalSplitButton    matlab.ui.control.ToggleButton
        VerticalSplitButton      matlab.ui.control.ToggleButton
        CropButton               matlab.ui.control.ToggleButton
        NoneButton               matlab.ui.control.ToggleButton
        NavigatePanel            matlab.ui.container.Panel
        GotoframeEditField       matlab.ui.control.NumericEditField
        GotoframeLabel           matlab.ui.control.Label
        BackButton               matlab.ui.control.Button
        ForwardButton            matlab.ui.control.Button
        ExportPanel              matlab.ui.container.Panel
        ProcessMultipleButton    matlab.ui.control.Button
        OutPathEditField         matlab.ui.control.EditField
        FixFileNamesCheckBox     matlab.ui.control.CheckBox
        SelectPathButton         matlab.ui.control.Button
        CompressionSpinner       matlab.ui.control.Spinner
        CompressionSpinnerLabel  matlab.ui.control.Label
        SampleDurationSpinner    matlab.ui.control.Spinner
        DurationsLabel           matlab.ui.control.Label
        SplitSpinner             matlab.ui.control.Spinner
        SplitVideoButton         matlab.ui.control.Button
        ProcessVideoButton       matlab.ui.control.Button
        ProcessSampleButton      matlab.ui.control.Button
        ProcessandTrimPanel      matlab.ui.container.Panel
        NegateCheckBox           matlab.ui.control.CheckBox
        AdjustContrastPanel      matlab.ui.container.Panel
        MaxPxSlider              matlab.ui.control.Slider
        MaxSliderLabel           matlab.ui.control.Label
        MinPxSlider              matlab.ui.control.Slider
        MinSliderLabel           matlab.ui.control.Label
        GaussianBlurSpinner      matlab.ui.control.Spinner
        GaussianBlurLabel        matlab.ui.control.Label
        PlaybackSpinner          matlab.ui.control.Spinner
        PlaybackSpinnerLabel     matlab.ui.control.Label
        TrimFramesSpinner        matlab.ui.control.Spinner
        TrimFramesSpinnerLabel   matlab.ui.control.Label
        FrameHistogramAxes       matlab.ui.control.UIAxes
        VidNameLabel             matlab.ui.control.Label
        LoadVideoButton          matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        vidName % Name of original video
        vidPath % Path to original video
        vidExtension
        VR % videoReader object
        vidLoaded % Boolean to check if a file is loaded
        currFrame % Frame currently displayed
        currFrameInd % Index of frame currently displayed
        ROIType % Can by 'H', 'V', or 'C'
        vidROI % ROI object, can be a line or box, based on process type
        ROIDrawn % Boolean to track if ROI exists or not
        ROIPlaceholder = struct; % Holds ROI information in between drawing frames
        drawingArea % Limits where you can draw the ROI
        codec % Uses either MPEG4 or the default used by FFmpeg
        rotation % 0, 90, 180, or 270. Rotations are clockwise
        frameHeight % Dimensions of current frame (after rotating)
        frameWidth
        multVidDialogHandle % For storing the info from the dialog box, created when processing multiple videos
        processMultContinue % Altered by dialog box
    end
    
    methods (Access = private)
        % Turn app UI elements on and off
        function enableDisable(app,enableFlag)
            components = [app.BackButton,app.ForwardButton,...
                app.GotoframeEditField,...
                app.ProcessVideoButton, app.ProcessSampleButton,...
                app.ProcessMultipleButton,...
                app.SplitVideoButton,...
                app.ROIButtonGroup.Buttons,app.RotationButtonGroup.Buttons];
            set(components,'Enable',enableFlag);
        end
        
        % Draw current video frame and update the title
        function updateAxes(app)
            app.currFrame = read(app.VR,app.currFrameInd);
            % Rotate - imrotate rotates counterclockwise, we want to rotate
            % clockwise. Converting to a negative value accomplishes this
            if app.rotation ~= 0
                app.currFrame = imrotate(app.currFrame,-app.rotation);
            end
            if app.NegateCheckBox.Value == 1
                app.currFrame = imcomplement(app.currFrame);
            end
            
            if app.ROIDrawn
                tempROI = copyobj(app.vidROI,app.ROIPlaceholder);
            end
            % Apply the gaussian filter
            if app.GaussianBlurSpinner.Value > 0
                app.currFrame = imgaussfilt(...
                    app.currFrame,app.GaussianBlurSpinner.Value);
            end

            % Get the histogram of the image values to help with adjustments
            [HC,bins] = histcounts(app.currFrame(:,:,1));
            bar(app.FrameHistogramAxes,bins(1:end-1),HC,...
                'FaceColor',[0.6 0.6 0.6],...
                'EdgeColor','none',...
                'BarWidth',1);
            hold(app.FrameHistogramAxes,'on');
            % Plot a line showing how the adjustment limits line up with
            % the pixel values.
            line(app.FrameHistogramAxes,...
                [app.MinPxSlider.Value,app.MaxPxSlider.Value],...
                [0,max(HC)],'Color','k');
            hold(app.FrameHistogramAxes,'off');
            xlim(app.FrameHistogramAxes,[0 255]);
            ylim(app.FrameHistogramAxes,[0 max(HC)]);
            % Display the frame adjusted by the specified min and max
            % values
            adjustedImage = imadjust(app.currFrame,...
                    [app.MinPxSlider.Value,...
                    app.MaxPxSlider.Value] / 255);
            imshow(adjustedImage,'Parent',app.UIAxes);
            set(app.UIAxes.Title,'String',['Frame ',num2str(app.currFrameInd)]);
            if app.ROIDrawn
                app.vidROI = copyobj(tempROI,app.UIAxes);
                %                 draw(app.vidROI);
            end
        end
        % Checks what ROI currently exists
        function getROIType(app)
            switch app.ROIButtonGroup.SelectedObject
                case app.NoneButton
                    app.ROIType = 'N';
                case app.CropButton
                    app.ROIType = 'C';
                case app.HorizontalSplitButton
                    app.ROIType = 'H';
                case app.VerticalSplitButton
                    app.ROIType = 'V';
            end
            
        end
        
        function getRotationValue(app)
            switch app.RotationButtonGroup.SelectedObject
                case app.RotButtonNone
                    app.rotation = 0;
                case app.RotButton90
                    app.rotation = 90;
                case app.RotButton180
                    app.rotation = 180;
                case app.RotButton270
                    app.rotation = 270;
            end
            
        end
        
        
        function S = getAppVars(app)
            % Grab all of the variables needed for the video preprocessing
            % function and put them into a struct array. This allows us to
            % pass these variables to a parallel loop when we want to split
            % the video.
            S.VidName = app.vidName;
            S.VidPath = app.vidPath;
            S.VR = app.VR;
            S.TrimFrames = app.TrimFramesSpinner.Value;
            S.MinPx = app.MinPxSlider.Value;
            S.MaxPx = app.MaxPxSlider.Value;
            S.Negate = app.NegateCheckBox.Value;
            S.Quality = app.CompressionSpinner.Value;
            S.GBlur = app.GaussianBlurSpinner.Value;
            S.ROIType = app.ROIType; 
            S.VidExt = app.vidExtension;
            S.Rotation = app.rotation;
            S.vidROI = app.vidROI;
            S.Codec = app.codec;
            S.TrimFrames = app.TrimFramesSpinner.Value;
            S.Setpts = app.PlaybackSpinner.Value;
            S.DoFixName = app.FixFileNamesCheckBox.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             hold(app.UIAxes,'on');
            % Disable all elements except the "Load Video" button.
            movegui(app.QuickVidAppUIFigure,"center");
            enableDisable(app,false)
            app.rotation = false;
            app.ROIType = 'N';
            app.ROIDrawn = false;
            
            
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            % Prompt the user to find a video file - looks for the
            % following right now:
            % - avi
            % - mkv
            % - mp4
            % These are the most commonly used video types in the lab
            
            [vidNameTemp,vidPathTemp] = uigetfile({'*.mp4';'*.avi';'*.mkv'});
            if isequal(vidNameTemp,0) || isempty(vidNameTemp)
                return
            end
            app.vidName = vidNameTemp;
            app.vidPath = vidPathTemp;
            set(app.OutPathEditField,'Value',app.vidPath)
            cd(app.vidPath)
            set(app.VidNameLabel,'Text',app.vidName);
            [~,~,app.vidExtension] = fileparts(app.vidName);
            % Create a video reader object and draw the first frame to the
            % axes
            app.VR = VideoReader(fullfile(app.vidPath,app.vidName));
            app.currFrameInd = 1;
            app.vidLoaded = true;
            updateAxes(app);
            % Get the dimensions of the image
            app.frameHeight = size(app.currFrame,1);
            app.frameWidth  = size(app.currFrame,2);
            % Prevent user from scrolling the image around
            disableDefaultInteractivity(app.UIAxes);
            % Set inital ROI type value
            getROIType(app)
            % Set the drawing area using
            app.drawingArea = [0,0,app.VR.Width,app.VR.Height];

            app.codec = 'mpeg4';
            enableDisable(app,true);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Step back one frame of video, if not already at beginning
            if app.currFrameInd > 1 && app.vidLoaded==true
                app.currFrameInd = app.currFrameInd - 1;
                updateAxes(app)
                %                 app.currFrame = read(app.VR,app.currFrameInd);
                %                 imshow(app.currFrame,'Parent',app.UIAxes)
            end
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if hasFrame(app.VR) && app.vidLoaded==true
                app.currFrameInd = app.currFrameInd + 1;
                updateAxes(app);
            end
        end

        % Selection changed function: ROIButtonGroup
        function ROIButtonGroupSelectionChanged(app, event)
            % Draw ROIs 
            % First get height and width from dimensions of current frame
            % This accounts for the rotation of the frame
            
            if app.ROIDrawn
                delete(app.vidROI);
            end
            app.ROIDrawn = false;
            getROIType(app);
            app.drawingArea = [0, 0, app.frameWidth, app.frameHeight];
            switch app.ROIType
                case 'N' % Placeholder, does nothing
                    
                case 'H'
                    app.vidROI = images.roi.Line(app.UIAxes,...
                        'InteractionsAllowed','translate',...
                        'Position',[0,0.5 * app.frameHeight;...
                        app.frameWidth, 0.5 * app.frameHeight],...
                        'DrawingArea',app.drawingArea);
                    app.ROIDrawn = true;
                case 'V'
                    app.vidROI = images.roi.Line(app.UIAxes,...
                        'InteractionsAllowed','translate',...
                        'Position',[0.5 * app.frameWidth,0;...
                        0.5 * app.frameWidth, app.frameHeight],...
                        'DrawingArea',app.drawingArea);
                    app.ROIDrawn = true;
                case 'C'
                    startPosition = [app.frameWidth * 0.25,...
                        app.frameHeight * 0.25,...
                        app.frameWidth * 0.5,...
                        app.frameHeight * 0.5];
                    app.vidROI = images.roi.Rectangle(app.UIAxes,...
                        'Position',startPosition,...
                        'DrawingArea',app.drawingArea);
                    app.ROIDrawn = true;
            end
            updateAxes(app)
        end

        % Button pushed function: ProcessVideoButton
        function ProcessVideoButtonPushed(app, event)

            % Run the current video through FFmpeg, cropping based on ROI
            % A time of all zeros tells the function "createFFmpegCommand"
            % to run the entire video.
            S = getAppVars(app);
            processVideoWrapper(S,'00:00:00',app.OutPathEditField.Value);
            
%             beep;
            disp('Done Processing Video');
            
        end

        % Button pushed function: ProcessSampleButton
        function ProcessSampleButtonPushed(app, event)
            % Run sample on only a short snippet of video. Duration is set
            % by the spinner in the GUI
            t = app.SampleDurationSpinner.Value;
            t = t / app.PlaybackSpinner.Value;
            duration = datestr(seconds(t),'HH:MM:SS');
            S = getAppVars(app);
            processVideoWrapper(S,duration,app.OutPathEditField.Value);
            disp('Done processing video sample.');
        end

        % Value changed function: GotoframeEditField
        function GotoframeEditFieldValueChanged(app, event)
            app.currFrameInd = app.GotoframeEditField.Value;
            updateAxes(app);
        end

        % Value changed function: NegateCheckBox
        function NegateCheckBoxValueChanged(app, event)
%             value = app.NegateCheckBox.Value;
            updateAxes(app);
        end

        % Button pushed function: SplitVideoButton
        function SplitVideoButtonPushed(app, event)
        % Split the video into multiple parts using parallel processing.
        % Split videos can later be analyzed in parallel as well.
            nSplits = app.SplitSpinner.Value;
            % Generate start times and durations for each video by evenly
            % splitting the video by n. We will then adjust the first video
            % to account for the frames we want to trim from the beginning.
            
            
            outPath = fullfile(app.OutPathEditField.Value,'Split_Videos');
            if ~exist(outPath,'dir')
                mkdir(outPath)
            end
            S = getAppVars(app);
            processVideoWrapper_split(S,outPath,nSplits)
            
            
%             for i = 1:nSplits
%                 j = nSplits - i + 1;
%                 processVideoWrapper(app,duration_times{j},...
%                     start_frames(j),outPath,num2str(j))
%             end
            
        end

        % Selection changed function: RotationButtonGroup
        function RotationButtonGroupSelectionChanged(app, event)
%             selectedButton = app.RotationButtonGroup.SelectedObject;
            getRotationValue(app);
            updateAxes(app);
            % Get the dimensions of the newly rotated frame
            app.frameHeight = size(app.currFrame,1);
            app.frameWidth = size(app.currFrame,2);
            % Delete the current ROI and set the selected button back to
            % "None." Forcing the user to re-draw the ROI makes it less
            % likely that they will draw an ROI that FFMPEG can't process
            % (This can happen if you draw an ROI that excedes the
            % boundaries of the image.)
            if app.ROIDrawn
                delete(app.vidROI);
            end
            app.ROIDrawn = false;
            app.ROIButtonGroup.SelectedObject = app.NoneButton;
            getROIType(app);
            
        end

        % Value changed function: GaussianBlurSpinner
        function GaussianBlurSpinnerValueChanged(app, event)
            updateAxes(app);
            
        end

        % Value changed function: MinPxSlider
        function MinPxSliderValueChanged(app, event)
            updateAxes(app);
            
        end

        % Value changed function: MaxPxSlider
        function MaxPxSliderValueChanged(app, event)
            updateAxes(app);
            
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            tempDir = uigetdir(cd,'Select output folder:');
            if isequal(tempDir,0)
                return
            else
                set(app.OutPathEditField,'Value',tempDir);
            end
        end

        % Button pushed function: ProcessMultipleButton
        function ProcessMultipleButtonPushed(app, event)
            [videoNames,videoPath] = uigetfile({'*.mp4';'*.avi';'*.mkv'},...
                'Select all videos to process:',app.vidPath,...
                "MultiSelect","on");
            if isequal(videoNames,0)
                return
            end
            if ~iscell(videoNames)
                videoNames = {videoNames};
            end
            nVideos = length(videoNames);
            dialogApp = MultVidDialog(app,nVideos);
            enableDisable(app,false)
            waitfor(dialogApp);
            
            if app.processMultContinue
                S = getAppVars(app);
                S.VidPath = videoPath;
                for i =1:nVideos
                    disp("Processing video "+string(i)+" of "+string(nVideos)+".")
                    S.VidName = videoNames{i};
                    processVideoWrapper(S,'00:00:00',app.OutPathEditField.Value);
                end
                disp('Done Processing Videos');
                enableDisable(app,true);
            else
                enableDisable(app,true);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuickVidAppUIFigure = uifigure('Visible', 'off');
            app.QuickVidAppUIFigure.Name = 'QuickVid App';
            app.UIAxes = uiaxes(app.QuickVidAppUIFigure);
            title(app.UIAxes, 'Video Frame n')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadVideoButton = uibutton(app.QuickVidAppUIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.VidNameLabel = uilabel(app.QuickVidAppUIFigure);
            app.VidNameLabel.Text = {''; ''};
            app.ProcessandTrimPanel = uipanel(app.QuickVidAppUIFigure);
            app.ProcessandTrimPanel.Title = 'Process and Trim';
            app.FrameHistogramAxes = uiaxes(app.ProcessandTrimPanel);
            title(app.FrameHistogramAxes, 'Image Histogram')
            xlabel(app.FrameHistogramAxes, 'Pixel Value')
            ylabel(app.FrameHistogramAxes, 'Count')
            app.FrameHistogramAxes.XTick = [];
            app.FrameHistogramAxes.XTickLabel = '';
            app.FrameHistogramAxes.YTick = [];
            app.TrimFramesSpinnerLabel = uilabel(app.ProcessandTrimPanel);
            app.TrimFramesSpinnerLabel.Text = 'Trim Frames';
            app.TrimFramesSpinner = uispinner(app.ProcessandTrimPanel);
            app.TrimFramesSpinner.Limits = [0 Inf];
            app.TrimFramesSpinner.RoundFractionalValues = 'on';
            app.TrimFramesSpinner.Tooltip = {'Remove frames from the beginning of the video'};
            app.PlaybackSpinnerLabel = uilabel(app.ProcessandTrimPanel);
            app.PlaybackSpinnerLabel.Text = 'Playback';
            app.PlaybackSpinner = uispinner(app.ProcessandTrimPanel);
            app.PlaybackSpinner.Step = 0.01;
            app.PlaybackSpinner.Limits = [0.05 Inf];
            app.PlaybackSpinner.Tooltip = {'Set playback speed of output video. Higher = faster.'};
            app.PlaybackSpinner.Value = 1;
            app.GaussianBlurLabel = uilabel(app.ProcessandTrimPanel);
            app.GaussianBlurLabel.Text = 'Gaussian Blur';
            app.GaussianBlurSpinner = uispinner(app.ProcessandTrimPanel);
            app.GaussianBlurSpinner.Step = 0.1;
            app.GaussianBlurSpinner.Limits = [0 60];
            app.GaussianBlurSpinner.ValueChangedFcn = createCallbackFcn(app, @GaussianBlurSpinnerValueChanged, true);
            app.GaussianBlurSpinner.Tooltip = {'Apply a gaussian blur with a standard deviation of the specified number of pixels'};
            app.AdjustContrastPanel = uipanel(app.ProcessandTrimPanel);
            app.AdjustContrastPanel.Title = 'Adjust Contrast';
            app.MinSliderLabel = uilabel(app.AdjustContrastPanel);
            app.MinSliderLabel.Text = 'Min';
            app.MinPxSlider = uislider(app.AdjustContrastPanel);
            app.MinPxSlider.Limits = [0 255];
            app.MinPxSlider.MajorTicks = [0 125 255];
            app.MinPxSlider.ValueChangedFcn = createCallbackFcn(app, @MinPxSliderValueChanged, true);
            app.MinPxSlider.MinorTicks = [0 25 50 75 100 125 150 175 200 225 250];
            app.MinPxSlider.Tooltip = {'Minimum pixel value'};
            app.MaxSliderLabel = uilabel(app.AdjustContrastPanel);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxPxSlider = uislider(app.AdjustContrastPanel);
            app.MaxPxSlider.Limits = [0 255];
            app.MaxPxSlider.MajorTicks = [0 125 255];
            app.MaxPxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxPxSliderValueChanged, true);
            app.MaxPxSlider.MinorTicks = [0 25 50 75 100 125 150 175 200 225 250];
            app.MaxPxSlider.Tooltip = {'Maximum pixel value'};
            app.MaxPxSlider.Value = 255;
            app.NegateCheckBox = uicheckbox(app.ProcessandTrimPanel);
            app.NegateCheckBox.ValueChangedFcn = createCallbackFcn(app, @NegateCheckBoxValueChanged, true);
            app.NegateCheckBox.Text = 'Negate';
            app.ExportPanel = uipanel(app.QuickVidAppUIFigure);
            app.ExportPanel.Title = 'Export';
            app.ProcessSampleButton = uibutton(app.ExportPanel, 'push');
            app.ProcessSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessSampleButtonPushed, true);
            app.ProcessSampleButton.Tooltip = {'Process a short segment of the beginning of the video, specified by "Duration"'};
            app.ProcessSampleButton.Text = 'Process Sample';
            app.ProcessVideoButton = uibutton(app.ExportPanel, 'push');
            app.ProcessVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessVideoButtonPushed, true);
            app.ProcessVideoButton.Tooltip = {'Process the entire currently-loaded video.'};
            app.ProcessVideoButton.Text = 'Process Video';
            app.SplitVideoButton = uibutton(app.ExportPanel, 'push');
            app.SplitVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SplitVideoButtonPushed, true);
            app.SplitVideoButton.Tooltip = {'Split the current video into multiple videos of the same length. Runs in parallel if possible.'};
            app.SplitVideoButton.Text = 'Split Video';
            app.SplitSpinner = uispinner(app.ExportPanel);
            app.SplitSpinner.Limits = [2 16];
            app.SplitSpinner.RoundFractionalValues = 'on';
            app.SplitSpinner.Tooltip = {'Number of resulting videos after splitting.'};
            app.SplitSpinner.Value = 4;
            app.DurationsLabel = uilabel(app.ExportPanel);
            app.DurationsLabel.Text = 'Duration (s)';
            app.SampleDurationSpinner = uispinner(app.ExportPanel);
            app.SampleDurationSpinner.Limits = [1 999];
            app.SampleDurationSpinner.RoundFractionalValues = 'on';
            app.SampleDurationSpinner.Tooltip = {'Set duration of video samples'};
            app.SampleDurationSpinner.Value = 5;
            app.CompressionSpinnerLabel = uilabel(app.ExportPanel);
            app.CompressionSpinnerLabel.Text = 'Compression';
            app.CompressionSpinner = uispinner(app.ExportPanel);
            app.CompressionSpinner.Limits = [1 32];
            app.CompressionSpinner.RoundFractionalValues = 'on';
            app.CompressionSpinner.Tooltip = {'A higher compression value results in a lower quality video'};
            app.CompressionSpinner.Value = 1;
            app.SelectPathButton = uibutton(app.ExportPanel, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Tooltip = {'Specify path to save output videos'};
            app.SelectPathButton.Text = 'Select Path';
            app.FixFileNamesCheckBox = uicheckbox(app.ExportPanel);
            app.FixFileNamesCheckBox.Tooltip = {'Change the output file name to be compatible with certain programs by replacing hyphens with underscores'};
            app.FixFileNamesCheckBox.Text = 'Fix File Names';
            app.FixFileNamesCheckBox.Value = true;
            app.OutPathEditField = uieditfield(app.ExportPanel, 'text');
            app.OutPathEditField.Value = '//';
            app.ProcessMultipleButton = uibutton(app.ExportPanel, 'push');
            app.ProcessMultipleButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessMultipleButtonPushed, true);
            app.ProcessMultipleButton.Tooltip = {'Process the entire currently-loaded video.'};
            app.ProcessMultipleButton.Text = 'Process Multiple';
            app.NavigatePanel = uipanel(app.QuickVidAppUIFigure);
            app.NavigatePanel.Title = 'Navigate';
            app.ForwardButton = uibutton(app.NavigatePanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Tooltip = {'Forwards one frame'};
            app.ForwardButton.Text = {'>'; ''};
            app.BackButton = uibutton(app.NavigatePanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Tooltip = {'Backwards one frame'};
            app.BackButton.Text = {'<'; ''};
            app.GotoframeLabel = uilabel(app.NavigatePanel);
            app.GotoframeLabel.Text = 'Go to frame';
            app.GotoframeEditField = uieditfield(app.NavigatePanel, 'numeric');
            app.GotoframeEditField.Limits = [1 Inf];
            app.GotoframeEditField.RoundFractionalValues = 'on';
            app.GotoframeEditField.ValueChangedFcn = createCallbackFcn(app, @GotoframeEditFieldValueChanged, true);
            app.GotoframeEditField.Value = 1;
            app.ROIButtonGroup = uibuttongroup(app.QuickVidAppUIFigure);
            app.ROIButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROIButtonGroupSelectionChanged, true);
            app.ROIButtonGroup.Title = 'ROI';
            app.NoneButton = uitogglebutton(app.ROIButtonGroup);
            app.NoneButton.Tooltip = {'No cropping'};
            app.NoneButton.Text = {'None'; ''};
            app.NoneButton.Value = true;
            app.CropButton = uitogglebutton(app.ROIButtonGroup);
            app.CropButton.Tooltip = {'Select a rectangular region and crop'};
            app.CropButton.Text = {'Crop'; ''};
            app.VerticalSplitButton = uitogglebutton(app.ROIButtonGroup);
            app.VerticalSplitButton.Tooltip = {'Split video into two, vertically'};
            app.VerticalSplitButton.Text = 'Vertical Split';
            app.HorizontalSplitButton = uitogglebutton(app.ROIButtonGroup);
            app.HorizontalSplitButton.Tooltip = {'Split video into two, horizontally'};
            app.HorizontalSplitButton.Text = 'Horizontal Split';
            app.RotationButtonGroup = uibuttongroup(app.QuickVidAppUIFigure);
            app.RotationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RotationButtonGroupSelectionChanged, true);
            app.RotationButtonGroup.Title = 'Rotation (Clockwise)';
            app.RotButtonNone = uitogglebutton(app.RotationButtonGroup);
            app.RotButtonNone.Text = 'None';
            app.RotButtonNone.Value = true;
            app.RotButton90 = uitogglebutton(app.RotationButtonGroup);
            app.RotButton90.Text = '90°';
            app.RotButton180 = uitogglebutton(app.RotationButtonGroup);
            app.RotButton180.Text = '180°';
            app.RotButton270 = uitogglebutton(app.RotationButtonGroup);
            app.RotButton270.Text = '270°';
            % Show the figure after all components are created
            app.QuickVidAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuickVid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuickVidAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuickVidAppUIFigure)
        end
    end
end